classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Blickwinkel01Label           matlab.ui.control.Label
        AngabeBlickwinkel            matlab.ui.control.NumericEditField
        Ueberschrift                 matlab.ui.control.Label
        Gruppe11Label                matlab.ui.control.Label
        Beschreibung                 matlab.ui.control.Label
        Button3Ansichtberechnen      matlab.ui.control.Button
        LinkeAnsichtEditFieldLabel   matlab.ui.control.Label
        AngabeLinkeAnsicht           matlab.ui.control.EditField
        RechteAnsichtEditFieldLabel  matlab.ui.control.Label
        AngabeRechteAnsicht          matlab.ui.control.EditField
        PanelRechteAnsicht           matlab.ui.container.Panel
        PanelBerechneteAnsicht       matlab.ui.container.Panel
        PanelLinkeAnsicht            matlab.ui.container.Panel
        ButtonLinkeAnsichtladen      matlab.ui.control.Button
        ButtonRechteAnsichtLaden     matlab.ui.control.Button
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
 
        end

        % Button pushed function: ButtonLinkeAnsichtladen
        function ButtonLinkeAnsichtladenPushed(app, event)
            [leftImg,leftPath] = uigetfile( ...
            {'*.png;*.jpg;*.JPG;*.bmp','Pictures (*.png,*.jpg,*.bmp)'},...
            'Select a File');
            img1 = imread([leftPath leftImg]);
            app.AngabeLinkeAnsicht.Value = [leftPath leftImg];
            IGray1 = rgb_to_gray(img1);
            axes(app.PanelLinkeAnsicht.Position);
            imshow(IGray1, []);
        end

        % Button pushed function: ButtonRechteAnsichtLaden
        function ButtonRechteAnsichtLadenPushed(app, event)
            [rightImg,rightPath] = uigetfile( ...
            {'*.png;*.jpg;*.JPG;*.bmp','Pictures (*.png,*.jpg,*.bmp)'},...
            'Select a File');
            img2 = imread([rightPath rightImg]);
            app.AngabeRechteAnsicht.Value = [rightPath rightImg];
            IGray2 = rgb_to_gray(img2);
            imshow(IGray2);
        end

        % Value changed function: AngabeBlickwinkel
        function AngabeBlickwinkelValueChanged(app, event)
            p = app.AngabeBlickwinkel.Value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Blickwinkel01Label = uilabel(app.UIFigure);
            app.Blickwinkel01Label.Text = 'Blickwinkel [0-1]:';
            app.AngabeBlickwinkel = uieditfield(app.UIFigure, 'numeric');
            app.AngabeBlickwinkel.Limits = [0 1];
            app.AngabeBlickwinkel.ValueDisplayFormat = '%.2f';
            app.AngabeBlickwinkel.ValueChangedFcn = createCallbackFcn(app, @AngabeBlickwinkelValueChanged, true);
            app.Ueberschrift = uilabel(app.UIFigure);
            app.Ueberschrift.Text = 'Computer Vision Challenge 2018';
            app.Gruppe11Label = uilabel(app.UIFigure);
            app.Gruppe11Label.Text = 'Gruppe 11: Julia Stroebel, Oliver Doege, Andre Thommessen, Tim Janßen, Sebastian Huegler';
            app.Beschreibung = uilabel(app.UIFigure);
            app.Beschreibung.Text = {'Dieses Programm berechnet aus zwei Ansichten eine dritte Ansicht, die sich zwischen den beiden Ansichten befindet. Dazu geben Sie bitte die linke und rechte Ansicht einer Szene '; 'und den Blickwinkel des zu berechnenden Bildes an. Dieser wird prozentual angegeben, wobei 0 der linken Ansicht entspricht und 1 der rechten Ansicht.'; 'Durch Klick auf den Button "3. Ansicht berechnen" wird das Programm gestartet und die dritte Ansicht berechnet, die dann hier ausgegeben wird.'};
            app.Button3Ansichtberechnen = uibutton(app.UIFigure, 'push');
            app.Button3Ansichtberechnen.Text = '3. Ansicht berechnen';
            app.LinkeAnsichtEditFieldLabel = uilabel(app.UIFigure);
            app.LinkeAnsichtEditFieldLabel.Text = 'Linke Ansicht:';
            app.AngabeLinkeAnsicht = uieditfield(app.UIFigure, 'text');
            app.AngabeLinkeAnsicht.Editable = 'off';
            app.RechteAnsichtEditFieldLabel = uilabel(app.UIFigure);
            app.RechteAnsichtEditFieldLabel.Text = 'Rechte Ansicht:';
            app.AngabeRechteAnsicht = uieditfield(app.UIFigure, 'text');
            app.AngabeRechteAnsicht.Editable = 'off';
            app.PanelRechteAnsicht = uipanel(app.UIFigure);
            app.PanelRechteAnsicht.Title = 'Rechte Ansicht';
            app.PanelBerechneteAnsicht = uipanel(app.UIFigure);
            app.PanelBerechneteAnsicht.Title = 'Berechnete Ansicht';
            app.PanelLinkeAnsicht = uipanel(app.UIFigure);
            app.PanelLinkeAnsicht.Title = 'Linke Ansicht';
            app.ButtonLinkeAnsichtladen = uibutton(app.UIFigure, 'push');
            app.ButtonLinkeAnsichtladen.ButtonPushedFcn = createCallbackFcn(app, @ButtonLinkeAnsichtladenPushed, true);
            app.ButtonLinkeAnsichtladen.Text = 'Linke Ansicht laden';
            app.ButtonRechteAnsichtLaden = uibutton(app.UIFigure, 'push');
            app.ButtonRechteAnsichtLaden.ButtonPushedFcn = createCallbackFcn(app, @ButtonRechteAnsichtLadenPushed, true);
            app.ButtonRechteAnsichtLaden.Text = 'Rechte Ansicht laden';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end